classdef FeatureExtraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FeatureExtractionUIFigure   matlab.ui.Figure
        SelectedPath                matlab.ui.control.EditField
        FolderofImagesLabel         matlab.ui.control.Label
        BrowseButton                matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        RowsforGaussEditFieldLabel  matlab.ui.control.Label
        RowsforGaussEditField       matlab.ui.control.NumericEditField
        EmailEditFieldLabel         matlab.ui.control.Label
        EmailEditField              matlab.ui.control.EditField
    end


    properties (Access = private)
        ImgStore % Description
        FilePath % Description
        AspectData % Description
    end

    methods (Access = private)
    
        function MainFunction(app)
            mastertempA = cell(1,numel(app.ImgStore.Files));
            mastertempB = cell(1,numel(app.ImgStore.Files));
            parfor imageidx = 1:numel(app.ImgStore.Files)
                try
                    img = readimage(app.ImgStore,imageidx);
                    
                    redslice = img(:,:,1);
                    greenslice = img(:,:,2);
                    
                    redthresh = multithresh(redslice);
                    redbin_slice = redslice > redthresh;
                    redprops = regionprops(redbin_slice,'BoundingBox');
                    if numel(redprops) ~= 2
                        continue
                    end
                    redslice(~redbin_slice) = 0;
                    
                    [PointAR,PointBR] = GetBrightestPixels(app,redslice,redprops);
                    Midpoint = round((PointAR+PointBR)/2);
                    Vector = [PointBR(1) - PointAR(1),PointBR(2) - PointAR(2)];
                    angle = acosd(dot(Vector,[1,0])/norm(Vector));
                    if Vector(2) > 0
                        greenslice = rotateAround(greenslice,Midpoint(2),Midpoint(1),angle,'Bicubic');
                        redslice = rotateAround(redslice,Midpoint(2),Midpoint(1),angle,'Bicubic');
                    else
                        greenslice = rotateAround(greenslice,Midpoint(2),Midpoint(1),-angle,'Bicubic');
                        redslice = rotateAround(redslice,Midpoint(2),Midpoint(1),-angle,'Bicubic');
                    end
                    
                    redthresh = multithresh(redslice);
                    redbin_slice = redslice > redthresh;
                    redprops = regionprops(redbin_slice,'BoundingBox');
                    
                    greenthresh = multithresh(greenslice);
                    greenbin_slice = greenslice > greenthresh;
                    greenprops = regionprops(greenbin_slice,'BoundingBox');
                    if numel(greenprops) ~= 2
                        continue
                    end
                    
                    greenslice(~greenbin_slice) = 0;
                    redslice(~redbin_slice) = 0;
                    
                    
                    [PointAG,PointBG] = GetBrightestPixels(app,greenslice,greenprops);
                    [PointAR,PointBR] = GetBrightestPixels(app,redslice,redprops);
                    if norm(PointAR-PointAG) < norm(PointBR-PointAG)
                        XPositionA = abs(PointAR(1)-PointAG(1))*64.5;
                        XPositionB = abs(PointBR(1)-PointBG(1))*64.5;
                        DistanceA = norm(PointAR-PointAG)*64.5;
                        DistanceB = norm(PointBR-PointBG)*64.5;
                    else
                        XPositionA = (abs(PointBR(1)-PointAG(1)))*64.5;
                        XPositionB = (abs(PointAR(1)-PointBG(1)))*64.5;
                        DistanceA = norm(PointBR-PointAG)*64.5;
                        DistanceB = norm(PointAR-PointBG)*64.5;
                    end
                    KKDistance = norm(PointBG-PointAG)*64.5;
                    SpindleLength = norm(PointAR-PointBR)*64.5;
                    
                    [propAR,propBR] = GetProps(app,redslice,redprops);
                    [propAG,propBG] = GetProps(app,greenslice,greenprops);
                    
                    [xsigmaAG,ysigmaAG] = CalcSigma(app,propAG);
                    [xsigmaBG,ysigmaBG] = CalcSigma(app,propBG);
                    
                    [xsigmaAR,ysigmaAR] = CalcSigma(app,propAR);
                    [xsigmaBR,ysigmaBR] = CalcSigma(app,propBR);
                    
                    [FWHMAR,FWHMBR,AR5x5Slice,BR5x5Slice] = GetFWHMAnd5x5(app,redslice,PointAR,PointBR);
                    [FWHMAG,FWHMBG,AG5x5Slice,BG5x5Slice] = GetFWHMAnd5x5(app,greenslice,PointAG,PointBG);
                    
                    tmpA = cell(1,13);
                    tmpA{1,1} = FWHMAR*64.5;
                    tmpA{1,2} = FWHMAG*64.5;
                    tmpA{1,3} = xsigmaAR*64.5;
                    tmpA{1,4} = ysigmaAR*64.5;
                    tmpA{1,5} = xsigmaAG*64.5;
                    tmpA{1,6} = ysigmaAG*64.5;
                    tmpA{1,7} = mean(AR5x5Slice,'all');
                    tmpA{1,8} = std2(AR5x5Slice);
                    tmpA{1,9} = mean(AG5x5Slice,'all');
                    tmpA{1,10} = std2(AG5x5Slice);
                    tmpA{1,11} = XPositionA/SpindleLength;
                    if XPositionA/SpindleLength >= 1
                        [~,basename,~] = fileparts(app.ImgStore.Files{imageidx});
                        disp(strcat('Problem with X Position A:',basename));
                        continue
                    end
                    tmpA{1,12} = DistanceA/SpindleLength;
                    if DistanceA/SpindleLength >= 1
                        [~,basename,~] = fileparts(app.ImgStore.Files{imageidx});
                        disp(strcat('Problem with Distance A:',basename));
                        continue
                    end
                    tmpA{1,13} = KKDistance/SpindleLength;
                    if KKDistance/SpindleLength >= 1
                        [~,basename,~] = fileparts(app.ImgStore.Files{imageidx});
                        disp(strcat('Problem with KK Distance:',basename));
                        continue
                    end
                    
                    tmpB = cell(1,13);
                    tmpB{1,1} = FWHMBR*64.5;
                    tmpB{1,2} = FWHMBG*64.5;
                    tmpB{1,3} = xsigmaBR*64.5;
                    tmpB{1,4} = ysigmaBR*64.5;
                    tmpB{1,5} = xsigmaBG*64.5;
                    tmpB{1,6} = ysigmaBG*64.5;
                    tmpB{1,7} = mean(BR5x5Slice,'all');
                    tmpB{1,8} = std2(BR5x5Slice);
                    tmpB{1,9} = mean(BG5x5Slice,'all');
                    tmpB{1,10} = std2(BG5x5Slice);
                    tmpB{1,11} = XPositionB/SpindleLength;
                    if XPositionB/SpindleLength >= 1
                        [~,basename,~] = fileparts(app.ImgStore.Files{imageidx});
                        disp(strcat('Problem with X Position A:',basename));
                        continue
                    end
                    tmpB{1,12} = DistanceB/SpindleLength;
                    if DistanceB/SpindleLength >= 1
                        [~,basename,~] = fileparts(app.ImgStore.Files{imageidx});
                        disp(strcat('Problem with Distance A:',basename));
                        continue
                    end
                    tmpB{1,13} = KKDistance/SpindleLength;
                    
                    mastertempA{imageidx} = tmpA;
                    mastertempB{imageidx} = tmpB;
                catch
                    [~,basename,~] = fileparts(app.ImgStore.Files{imageidx});
                    disp(strcat('General Error:',basename));
                    continue
                end
            end
            mastertemp = [mastertempA, mastertempB];
            mastertemp =  mastertemp(~cellfun('isempty',mastertemp));
            master = ReorganizeCellArray(app, mastertemp);
            master = cell2mat(master);
            SpotHeightR = master(:,1);
            SpotHeightG = master(:,2);
            STDinXR = master(:,3);
            STDinYR = master(:,4);
            STDinXG = master(:,5);
            STDinYG = master(:,6);
            MeanIntensityR = master(:,7);
            MeanSTDR = master(:,8);
            MeanIntensityG = master(:,9);
            MeanSTDG = master(:,10);
            XPosition = master(:,11);
            DistanceToPoleBody = master(:,12);
            KKDistance = master(:,13);
            master = table(SpotHeightR,SpotHeightG,STDinXR,STDinYR,STDinXG,STDinYG,MeanIntensityR,MeanSTDR,MeanIntensityG,MeanSTDG,XPosition,DistanceToPoleBody,KKDistance);
            save FeatureData master
            disp('Done!');
        end
    
        function [PointA, PointB] = GetBrightestPixels(~,img,props)
            [~,I1] = max(img(:));
            [y1,x1] = ind2sub([size(img,1),size(img,2)],I1);
            pc = props(1).BoundingBox;
            if ((x1 >= ceil(pc(1))) && (x1 <= (ceil(pc(1))+pc(3)-1))) && ((y1 >= ceil(pc(2))) && (y1 <= (ceil(pc(2))+pc(4)-1)))
                img(ceil(pc(2)):ceil(pc(2))+pc(4)-1,ceil(pc(1)):ceil(pc(1))+pc(3)-1) = 0;
            else
                pc = props(2).BoundingBox;
                img(ceil(pc(2)):ceil(pc(2))+pc(4)-1,ceil(pc(1)):ceil(pc(1))+pc(3)-1) = 0;
            end
            [~,I2] = max(img(:));
            [y2,x2] = ind2sub([size(img,1),size(img,2)],I2);
            if x1 < x2
                PointA = [x1 y1];
                PointB = [x2,y2];
            else
                PointA = [x2,y2];
                PointB = [x1,y1];
            end
        end
    
        function [Prop1,Prop2] = GetProps(~,slice,props)
            pc = props(1).BoundingBox;
            Prop1 = slice(ceil(pc(2)):ceil(pc(2))+pc(4)-1,ceil(pc(1)):ceil(pc(1))+pc(3)-1);
            pc = props(2).BoundingBox;
            Prop2 = slice(ceil(pc(2)):ceil(pc(2))+pc(4)-1,ceil(pc(1)):ceil(pc(1))+pc(3)-1);
        end
        
        function [sigmax,sigmay] = CalcSigma(app,M)
            xsum = sum(M,1);
            xdata = Counts2Data(app,xsum);
            sigmax = std(xdata);
            ysum = sum(M,2);
            ydata = Counts2Data(app,ysum');
            sigmay = std(ydata);
        end
    
        function data = Counts2Data(~,BinCounts)
            BinCounts = round(4095*(BinCounts-min(BinCounts))/(max(BinCounts)-min(BinCounts)));
            CoM = cell(1,length(BinCounts));
            for binidx = 1:length(BinCounts)
                CoM{binidx} = repmat(binidx,1,BinCounts(binidx));
            end
            data = [];
            for comidx = 1:length(CoM)
                data = [data CoM{comidx}];
            end
        end
    
        function [FWHMA,FWHMB,A5x5Slice,B5x5Slice] = GetFWHMAnd5x5(app,slice,PointA,PointB)
            paddedslice = padarray(slice,[10 10],0,'both');
            PaddedA = [PointA(1)+10,PointA(2)+10];
            PaddedB = [PointB(1)+10,PointB(2)+10];
            A5x5Slice = paddedslice(PaddedA(2)-2:PaddedA(2)+2,PaddedA(1)-2:PaddedA(1)+2);
            B5x5Slice = paddedslice(PaddedB(2)-2:PaddedB(2)+2,PaddedB(1)-2:PaddedB(1)+2);
            sliceA = paddedslice(PaddedA(2)-10:PaddedA(2)+10,PaddedA(1)-10:PaddedA(1)+10);
            sliceB = paddedslice(PaddedB(2)-10:PaddedB(2)+10,PaddedB(1)-10:PaddedB(1)+10);
            [FWHMA, ~] = calcFwhm(sliceA,app.RowsforGaussEditField.Value);
            [FWHMB, ~] = calcFwhm(sliceB,app.RowsforGaussEditField.Value);
        end
        
        function GenerateImgFromBrowse(app)
            app.FilePath = uigetdir('','Select folder that contains the images');
            app.FeatureExtractionUIFigure.Visible = 'off';
            app.FeatureExtractionUIFigure.Visible = 'on';
            try
                app.SelectedPath.Value = app.FilePath;
            catch
                app.ImgStore = [];
                app.FilePath = [];
                return
            end
            GenerateDataStore(app)
        end
    
        function GenerateImgFromEditField(app)
            app.FilePath = app.SelectedPath.Value;
            existvalue = exist(app.FilePath,'dir');
            if existvalue ~= 7
                errordlg('Folder does not exist','Error!')
                app.SelectedPath.Value = '';
                app.ImgStore = [];
                app.FilePath = [];
            end
            GenerateDataStore(app)
        end
 
        function GenerateDataStore(app)
            try
                app.ImgStore = imageDatastore(app.FilePath);
            catch
                app.ImgStore = [];
                app.FilePath = [];
                errordlg('No images found and/or non-image files found','Error!')
                return
            end
        end
        
        function finalarray = ReorganizeCellArray(~,inputarray)
            finalarray = cell(numel(inputarray),13);
            for idx = 1:numel(inputarray)
                finalarray{idx,1} = inputarray{idx}{1};
                finalarray{idx,2} = inputarray{idx}{2};
                finalarray{idx,3} = inputarray{idx}{3};
                finalarray{idx,4} = inputarray{idx}{4};
                finalarray{idx,5} = inputarray{idx}{5};
                finalarray{idx,6} = inputarray{idx}{6};
                finalarray{idx,7} = inputarray{idx}{7};
                finalarray{idx,8} = inputarray{idx}{8};
                finalarray{idx,9} = inputarray{idx}{9};
                finalarray{idx,10} = inputarray{idx}{10};
                finalarray{idx,11} = inputarray{idx}{11};
                finalarray{idx,12} = inputarray{idx}{12};
                finalarray{idx,13} = inputarray{idx}{13};
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            MainFunction(app)
            SendEmail('Feature Extraction',app.EmailEditField.Value,'Completed Calculations!');
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            GenerateImgFromBrowse(app)
        end

        % Callback function: SelectedPath, SelectedPath
        function SelectedPathValueChanged(app, event)
            GenerateImgFromEditField(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FeatureExtractionUIFigure = uifigure('Visible', 'off');
            app.FeatureExtractionUIFigure.Name = 'Feature Extraction';
            app.SelectedPath = uieditfield(app.FeatureExtractionUIFigure, 'text');
            app.SelectedPath.ValueChangedFcn = createCallbackFcn(app, @SelectedPathValueChanged, true);
            app.SelectedPath.ValueChangingFcn = createCallbackFcn(app, @SelectedPathValueChanged, true);
            app.FolderofImagesLabel = uilabel(app.FeatureExtractionUIFigure);
            app.FolderofImagesLabel.Text = 'Folder of Images';
            app.BrowseButton = uibutton(app.FeatureExtractionUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RunButton = uibutton(app.FeatureExtractionUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RowsforGaussEditFieldLabel = uilabel(app.FeatureExtractionUIFigure);
            app.RowsforGaussEditFieldLabel.Text = 'Rows for Gauss';
            app.RowsforGaussEditField = uieditfield(app.FeatureExtractionUIFigure, 'numeric');
            app.RowsforGaussEditField.UpperLimitInclusive = 'off';
            app.RowsforGaussEditField.Limits = [1 Inf];
            app.RowsforGaussEditField.Value = 15;
            app.EmailEditFieldLabel = uilabel(app.FeatureExtractionUIFigure);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.FeatureExtractionUIFigure, 'text');
            % Show the figure after all components are created
            app.FeatureExtractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FeatureExtraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FeatureExtractionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FeatureExtractionUIFigure)
        end
    end
end