classdef FeatureExtractionForPCA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FeatureExtractionforPCAUIFigure  matlab.ui.Figure
        SelectedPath                matlab.ui.control.EditField
        FolderofImagesLabel         matlab.ui.control.Label
        BrowseButton                matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        RowsforGaussEditFieldLabel  matlab.ui.control.Label
        RowsforGaussEditField       matlab.ui.control.NumericEditField
        EmailEditFieldLabel         matlab.ui.control.Label
        EmailEditField              matlab.ui.control.EditField
    end


    properties (Access = private)
        ImgStore % Description
        FilePath % Description
        AspectData % Description
    end

    methods (Access = private)
    
        function MainFunction(app)
            mastertempA = cell(1,numel(app.ImgStore.Files));
            mastertempB = cell(1,numel(app.ImgStore.Files));
            parfor imageidx = 1:numel(app.ImgStore.Files)
                try
                img = readimage(app.ImgStore,imageidx);
                slice = img(:,:,2);
                thresh = multithresh(slice);
                bin_slice = slice > thresh;
                props = regionprops(bin_slice,'centroid');
                if numel(props) ~= 2
                    continue
                end
                slice = img(:,:,1);
                thresh = multithresh(slice);
                bin_slice = slice > thresh;
                props = regionprops(bin_slice,'centroid');
                if numel(props) ~= 2
                    continue
                end
                redslice = img(:,:,1);
                [PointA1,PointB1] = GetBrightestPixels(app,slice);
                Midpoint = round((PointA1+PointB1)/2);
                Vector = [PointB1(1) - PointA1(1),PointB1(2) - PointA1(2)];
                angle = acosd(dot(Vector,[1,0])/norm(Vector));
                slice = img(:,:,2);
                if Vector(2) > 0
                    slice = rotateAround(slice,Midpoint(2),Midpoint(1),angle,'Bicubic');
                    redslice = rotateAround(redslice,Midpoint(2),Midpoint(1),angle,'Bicubic');
                else
                    slice = rotateAround(slice,Midpoint(2),Midpoint(1),-angle,'Bicubic');
                    redslice = rotateAround(redslice,Midpoint(2),Midpoint(1),-angle,'Bicubic');
                end
                thresh = multithresh(slice);
                bin_slice = slice > thresh;
                props = regionprops(bin_slice,'centroid');
                if numel(props) ~= 2
                    continue
                end
                [PointA2,PointB2] = GetBrightestPixels(app,slice);
                [PointA1,PointB1] = GetBrightestPixels(app,redslice);
                if norm(PointA1-PointA2) < norm(PointB1-PointA2)
                    XPositionA = abs(PointA1(1)-PointA2(1))*64.5;
                    XPositionB = abs(PointB1(1)-PointB2(1))*64.5;
                    DistanceA = norm(PointA1-PointA2)*64.5;
                    DistanceB = norm(PointB1-PointB2)*64.5;
                else
                    XPositionA = (abs(PointB1(1)-PointA2(1)))*64.5;
                    XPositionB = (abs(PointA1(1)-PointB2(1)))*64.5;
                    DistanceA = norm(PointB1-PointA2)*64.5;
                    DistanceB = norm(PointA1-PointB2)*64.5;
                end
                KKDistance = norm(PointB2-PointA2)*64.5;
                SpindleLength = norm(PointA1-PointB1)*64.5;
                slice(~bin_slice) = 0;
                if slice(PointA2(2),PointA2(1)) == 0 || slice(PointB2(2),PointB2(1)) == 0
                    continue
                end
                xany = any(slice,1);
                [x1left,x1right] = BindingRanges(app,xany,PointA2(1));
                [x2left,x2right] = BindingRanges(app,xany,PointB2(1));
                props1 = slice(:,x1left:x1right);
                props2 = slice(:,x2left:x2right);
                yany = any(props1,2);
                [y1up,y1down] = BindingRanges(app,yany.',PointA2(2));
                yany = any(props2,2);
                [y2up,y2down] = BindingRanges(app,yany.',PointB2(2));
                props1 = props1(y1up:y1down,:);
                props2 = props2(y2up:y2down,:);
                [xsigma1,ysigma1] = CalcSigma(app,props1);
                [xsigma2,ysigma2] = CalcSigma(app,props2);
                paddedslice = padarray(slice,[10 10],0,'both');
                PaddedA = [PointA2(1)+10,PointA2(2)+10];
                PaddedB = [PointB2(1)+10,PointB2(2)+10];
                A5x5Slice = paddedslice(PaddedA(2)-2:PaddedA(2)+2,PaddedA(1)-2:PaddedA(1)+2);
                B5x5Slice = paddedslice(PaddedB(2)-2:PaddedB(2)+2,PaddedB(1)-2:PaddedB(1)+2);
                sliceA = paddedslice(PaddedA(2)-10:PaddedA(2)+10,PaddedA(1)-10:PaddedA(1)+10);
                sliceB = paddedslice(PaddedB(2)-10:PaddedB(2)+10,PaddedB(1)-10:PaddedB(1)+10);
                [FWHMA, ~] = calcFwhm(sliceA,app.RowsforGaussEditField.Value);
                [FWHMB, ~] = calcFwhm(sliceB,app.RowsforGaussEditField.Value);
                tmpA = cell(1,9);
                tmpA{1,1} = FWHMA*64.5;
                tmpA{1,2} = XPositionA/SpindleLength;
                if XPositionA/SpindleLength >= 1
                    [~,basename,~] = fileparts(app.ImgStore.Files{imageidx})
                    disp(strcat('Problem with X Position A:',basename));
                    continue
                end
                tmpA{1,3} = DistanceA/SpindleLength;
                if DistanceA/SpindleLength >= 1
                    [~,basename,~] = fileparts(app.ImgStore.Files{imageidx})
                    disp(strcat('Problem with Distance A:',basename));
                    continue
                end
                tmpA{1,4} = xsigma1*64.5;
                tmpA{1,5} = ysigma1*64.5;
                tmpA{1,6} = KKDistance/SpindleLength;
                if KKDistance/SpindleLength >= 1
                    [~,basename,~] = fileparts(app.ImgStore.Files{imageidx})
                    disp(strcat('Problem with KK Distance:',basename));
                    continue
                end
                tmpA{1,7} = SpindleLength/SpindleLength;
                tmpA{1,8} = mean(A5x5Slice,'all');
                tmpA{1,9} = std2(A5x5Slice);
                tmpB = cell(1,9);
                tmpB{1,1} = FWHMB*64.5;
                tmpB{1,2} = XPositionB/SpindleLength;
                if XPositionB/SpindleLength >= 1
                    [~,basename,~] = fileparts(app.ImgStore.Files{imageidx})
                    disp(strcat('Problem with X Position B:',basename));
                    continue
                end
                tmpB{1,3} = DistanceB/SpindleLength;
                if DistanceB/SpindleLength >= 1
                    [~,basename,~] = fileparts(app.ImgStore.Files{imageidx})
                    disp(strcat('Problem with Distance B:',basename));
                    continue
                end
                tmpB{1,4} = xsigma2*64.5;
                tmpB{1,5} = ysigma2*64.5;
                tmpB{1,6} = KKDistance/SpindleLength;
                tmpB{1,7} = SpindleLength/SpindleLength;
                tmpB{1,8} = mean(B5x5Slice,'all');
                tmpB{1,9} = std2(B5x5Slice);
                mastertempA{imageidx} = tmpA;
                mastertempB{imageidx} = tmpB;
                catch
                    [~,basename,~] = fileparts(app.ImgStore.Files{imageidx})
                    disp(strcat('General Error:',basename));
                    continue
                end
            end
            mastertemp = [mastertempA, mastertempB];
            mastertemp =  mastertemp(~cellfun('isempty',mastertemp));
            master = ReorganizeCellArray(app, mastertemp);
            master = cell2mat(master);
            SpotHeight = master(:,1);
            XPosition = master(:,2);
            DistanceToPoleBody = master(:,3);
            STDinX = master(:,4);
            STDinY = master(:,5);
            KKDistance = master(:,6);
            SpindleLength = master(:,7);
            MeanIntensity = master(:,8);
            MeanSTD = master(:,9);
            master = table(SpotHeight,XPosition,DistanceToPoleBody,STDinX,STDinY,KKDistance,SpindleLength,MeanIntensity,MeanSTD);
            save AspectRatioData master
            disp('Done!');
        end
        
        function GenerateImgFromBrowse(app)
            app.FilePath = uigetdir('','Select folder that contains the images');
            app.FeatureExtractionforPCAUIFigure.Visible = 'off';
            app.FeatureExtractionforPCAUIFigure.Visible = 'on';
            try
                app.SelectedPath.Value = app.FilePath;
            catch
                app.ImgStore = [];
                app.FilePath = [];
                ResetChannels(app)
                return
            end
            GenerateDataStore(app)
        end
    
        function GenerateImgFromEditField(app)
            app.FilePath = app.SelectedPath.Value;
            existvalue = exist(app.FilePath,'dir');
            if existvalue ~= 7
                errordlg('Folder does not exist','Error!')
                app.SelectedPath.Value = '';
                app.ImgStore = [];
                app.FilePath = [];
                ResetChannels(app)
            end
            GenerateDataStore(app)
        end
 
        function GenerateDataStore(app)
            try
                app.ImgStore = imageDatastore(app.FilePath);
            catch
                app.ImgStore = [];
                app.FilePath = [];
                ResetChannels(app)
                return
            end
        end
    
        function [PointA, PointB] = GetBrightestPixels(~,img)
            img = padarray(img,[3,3],0,'both');
            [~,I1] = max(img(:));
            [y1,x1] = ind2sub([size(img,1),size(img,2)],I1);
            tempimg = img;
            tempimg(y1-3:y1+3,x1-3:x1+3) = 0;
            [~,I2] = max(tempimg(:));
            [y2,x2] = ind2sub([size(tempimg,1),size(tempimg,2)],I2);
            if x1 < x2
                PointA = [x1-3 y1-3];
                PointB = [x2-3,y2-3];
            else
                PointA = [x2-3,y2-3];
                PointB = [x1-3,y1-3];
            end
        end

        function [left,right] = BindingRanges(~,array,seed)
            templeft = seed;
            tempright = seed;
            if templeft == 1
                left = 1;
            else
                while array(templeft - 1) == 1
                    templeft = templeft - 1;
                    if templeft == 1
                        break
                    end
                end
                left = templeft;
            end
            if tempright == length(array)
                right = length(array);
            else
                while array(tempright + 1) == 1
                    tempright = tempright + 1;
                    if tempright == length(array)
                        break
                    end
                end
                right = tempright;
            end
        end
        
        function [sigmax,sigmay] = CalcSigma(app,M)
            xsum = sum(M,1);
            xdata = Counts2Data(app,xsum);
            f = fitdist(xdata','Normal');
            sigmax = f.sigma;
            ysum = sum(M,2);
            ydata = Counts2Data(app,ysum);
            f = fitdist(ydata','Normal');
            sigmay = f.sigma;
            
        end
        
        function SaveData(app)
            AspectRatioData = app.AspectData;
            save AspectRatioData AspectRatioData
            FWHMData.Raw = app.FWHM;
            FWHMData.Mean = mean(app.FWHM);
            FWHMData.STD = std(app.FWHM);
            save FWHMData FWHMData
        end
        
        function data = Counts2Data(~,BinCounts)
            CoM = cell(1,length(BinCounts));
            for binidx = 1:length(BinCounts)
                CoM{binidx} = repmat(binidx,1,BinCounts(binidx));
            end
            data = [];
            for comidx = 1:length(CoM)
                data = [data CoM{comidx}];
            end
            data = (data-min(data))/(max(data)-min(data));
        end
        
        function finalarray = ReorganizeCellArray(~,inputarray)
            finalarray = cell(numel(inputarray),10);
            for idx = 1:numel(inputarray)
                finalarray{idx,1} = inputarray{idx}{1};
                finalarray{idx,2} = inputarray{idx}{2};
                finalarray{idx,3} = inputarray{idx}{3};
                finalarray{idx,4} = inputarray{idx}{4};
                finalarray{idx,5} = inputarray{idx}{5};
                finalarray{idx,6} = inputarray{idx}{6};
                finalarray{idx,7} = inputarray{idx}{7};
                finalarray{idx,8} = inputarray{idx}{8};
                finalarray{idx,9} = inputarray{idx}{9};
            end
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            MainFunction(app)
            SendEmail('Feature Extraction',app.EmailEditField.Value,'Completed Calculations!');
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            GenerateImgFromBrowse(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FeatureExtractionforPCAUIFigure = uifigure;
            app.FeatureExtractionforPCAUIFigure.Name = 'Feature Extraction for PCA';
            app.SelectedPath = uieditfield(app.FeatureExtractionforPCAUIFigure, 'text');
            app.FolderofImagesLabel = uilabel(app.FeatureExtractionforPCAUIFigure);
            app.FolderofImagesLabel.Text = 'Folder of Images';
            app.BrowseButton = uibutton(app.FeatureExtractionforPCAUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RunButton = uibutton(app.FeatureExtractionforPCAUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RowsforGaussEditFieldLabel = uilabel(app.FeatureExtractionforPCAUIFigure);
            app.RowsforGaussEditFieldLabel.Text = 'Rows for Gauss';
            app.RowsforGaussEditField = uieditfield(app.FeatureExtractionforPCAUIFigure, 'numeric');
            app.RowsforGaussEditField.UpperLimitInclusive = 'off';
            app.RowsforGaussEditField.Limits = [1 Inf];
            app.RowsforGaussEditField.Value = 15;
            app.EmailEditFieldLabel = uilabel(app.FeatureExtractionforPCAUIFigure);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.FeatureExtractionforPCAUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = FeatureExtractionForPCA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FeatureExtractionforPCAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FeatureExtractionforPCAUIFigure)
        end
    end
end